classdef ManipulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlsPanel              matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        ExtensionEditField         matlab.ui.control.EditField
        ExtensionEditFieldLabel    matlab.ui.control.Label
        ForewardKinematicsLabel    matlab.ui.control.Label
        InverseKinematicsLabel     matlab.ui.control.Label
        ZCoordinateEditField       matlab.ui.control.EditField
        ZCoordinateEditFieldLabel  matlab.ui.control.Label
        YCoordinateEditField       matlab.ui.control.EditField
        YCoordinateEditFieldLabel  matlab.ui.control.Label
        XCoordinateEditField       matlab.ui.control.EditField
        XCoordinateEditFieldLabel  matlab.ui.control.Label
        AngleTwoEditField          matlab.ui.control.EditField
        AngleTwoEditFieldLabel     matlab.ui.control.Label
        AngleOneEditField          matlab.ui.control.EditField
        AngleOneEditFieldLabel     matlab.ui.control.Label
        RunIKineButton             matlab.ui.control.Button
        RunFKineButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunFKineButton
        function RunFKineButtonPushed(app, event)
            
            try    
                % World offset
                oset = transl(75, 0, 50);

                % % % % % SETUP
                % Setup Links from DH Table parameters
                %       0  d   a   α   σ
                dh = [  0  70  140 0   0   ;
                        0  50  140 pi  0   ; 
                        0  48  0   pi  1  ];


                % % % % % VALIDATION AND CONVERSION
                % Acquire angles from the UI
                q1 = str2double(app.AngleOneEditField.Value);
                q2 = str2double(app.AngleTwoEditField.Value);
                q3 = str2double(app.ExtensionEditField.Value);
    
                % Input validation
                if q1 > 180 || q2 > 180 || q1 < -180 || q2 < -180
                    error('Invalid input q1,2');
                end
                if q3 > 120 || q3 < 0
                    error('Invalid input q3');
                end

                % Movements
                Q(1) = q1 * pi/180;
                Q(2) = q2 * pi/180;
                Q(3) = q3 + 48;
    

                % % % % % PLOTTING
                % Generate robot and plot
                Robot = SerialLink(dh);
                Robot.name = " ";
                Robot.links(3).qlim = [0 100];
                
                T = Robot.fkine(Q);
                Robot.plot(Q)

                % Update the X Y Z coords
                app.XCoordinateEditField.Value = num2str(round(T.t(1)));
                app.YCoordinateEditField.Value = num2str(round(T.t(2)));
                app.ZCoordinateEditField.Value = num2str(round(T.t(3)));


            % Catch exceptions
            catch ex
                f = msgbox(ex.message,'Invalid Operation');
            end

        end

        % Button pushed function: RunIKineButton
        function RunIKineButtonPushed(app, event)
            try
                % % % % % SETUP
                % Setup Links from DH Table parameters
                %       0  d   a   α   σ (Rev. or Pris.)
                dh = [  0  70  140 0   0   ;
                        0  50  140 pi  0   ; 
                        0  48  0   pi  1  ];


                % % % % % VALIDATION AND CONVERSION
                % Read in coords from UI
                X = str2double(app.XCoordinateEditField.Value);
                Y = str2double(app.YCoordinateEditField.Value);
                Z = str2double(app.ZCoordinateEditField.Value);
                
                % Input validation
                r = sqrt(X^2 + Y^2);
                if r > 280
                    error('Invalid input X,Y');
                end
                if Z > 72 || Z < 0
                    error('Invalid input Z');
                end
                
                T = transl(X, Y, Z);
    

                % % % % % PLOTTING
                % Generate robot and plot
                Robot = SerialLink(dh);
                Robot.name = " ";
                Robot.links(3).qlim = [0 100];

                J = Robot.ikine(T, [0 0.1 72], 'mask', [1 1 1 0 0 0]);
                Robot.plot(J);

                % Update the UI
                app.AngleOneEditField.Value = num2str(round(J(1)*180/pi));
                app.AngleTwoEditField.Value = num2str(round(J(2)*180/pi));
                app.ExtensionEditField.Value = num2str(round(J(3)-48));
            

            catch ex
                f = msgbox(ex.message,'Invalid Operation');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout = uigridlayout(app.ControlsPanel);
            app.RunFKineButton = uibutton(app.GridLayout, 'push');
            app.RunFKineButton.ButtonPushedFcn = createCallbackFcn(app, @RunFKineButtonPushed, true);
            app.RunFKineButton.Text = 'Run F-Kine';
            app.RunIKineButton = uibutton(app.GridLayout, 'push');
            app.RunIKineButton.ButtonPushedFcn = createCallbackFcn(app, @RunIKineButtonPushed, true);
            app.RunIKineButton.Text = 'Run I-Kine';
            app.AngleOneEditFieldLabel = uilabel(app.GridLayout);
            app.AngleOneEditFieldLabel.Text = 'Angle One';
            app.AngleOneEditField = uieditfield(app.GridLayout, 'text');
            app.AngleOneEditField.Value = '0';
            app.AngleTwoEditFieldLabel = uilabel(app.GridLayout);
            app.AngleTwoEditFieldLabel.Text = 'Angle Two';
            app.AngleTwoEditField = uieditfield(app.GridLayout, 'text');
            app.AngleTwoEditField.Value = '0';
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.GridLayout, 'text');
            app.XCoordinateEditField.Value = '280';
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.GridLayout, 'text');
            app.YCoordinateEditField.Value = '0';
            app.ZCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.GridLayout, 'text');
            app.ZCoordinateEditField.Value = '72';
            app.InverseKinematicsLabel = uilabel(app.GridLayout);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.ForewardKinematicsLabel = uilabel(app.GridLayout);
            app.ForewardKinematicsLabel.Text = 'Foreward Kinematics';
            app.ExtensionEditFieldLabel = uilabel(app.GridLayout);
            app.ExtensionEditFieldLabel.Text = 'Extension';
            app.ExtensionEditField = uieditfield(app.GridLayout, 'text');
            app.ExtensionEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end