classdef ManipulatorApp_Grid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        InverseKinematicsPanel  matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        ZCoordinateSlider       matlab.ui.control.Slider
        ZCoordinateSliderLabel  matlab.ui.control.Label
        YCoordinateSlider       matlab.ui.control.Slider
        YCoordinateSliderLabel  matlab.ui.control.Label
        XCoordinateSlider       matlab.ui.control.Slider
        XCoordinateSliderLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ForwardKinematicsPanel  matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Link3mmSlider           matlab.ui.control.Slider
        Link3mmSliderLabel      matlab.ui.control.Label
        Link2degSlider          matlab.ui.control.Slider
        Link2degSliderLabel     matlab.ui.control.Label
        Link1degSlider          matlab.ui.control.Slider
        Link1degSliderLabel     matlab.ui.control.Label
    end

    %% GLOBAL VARIABLES
    properties (Access = private)
        ARM1_L = 140;   % Arm1 length
        ARM1_H = 120;   % Arm1 height
        ARM2_L = 140;   % Arm2 length
        ARM2_H = 50;    % Arm2 height
        EE_OS = 48;     % EE distance below the arm
        MAX_ANG = 135;  % Maximum angle the arms can acheive

        WS = [-300 300 -300 300 0 200]; % Robot workspace
    end
    
    methods (Access = private)
        
        %% Update the X Y Z coords on the GUI
        function UpdateCoords(app, T)

            % Weird MATLAB issue - engineered a workaround
            try
                X = round(T.t(1));
                Y = round(T.t(2));
                Z = round(T.t(3));
            catch
                X = round(T(1,4));
                Y = round(T(2,4));
                Z = round(T(3,4));
            end

            % Apply the variables to the GUI
            app.XCoordinateSlider.Value = X;
            app.YCoordinateSlider.Value = Y;
            app.ZCoordinateSlider.Value = Z;

        end

        %% Update the angles on the GUI
        function UpdateAngles(app, Q)
            
            % Parse the raw info from the matrix
            q1 = mod(round(Q(1)*180/pi),360);
            q2 = mod(round(Q(2)*180/pi),360);
            q3 = round(Q(3)-app.EE_OS);

            % Remove supernumerary full rotations
            % Move range between -180 -> +180
            if q1 > 180
                q1 = - 360 + q1;
            elseif q1 < -180
                q1 = 360 + q1;
            end
            if q2 > 180
                q2 = - 360 + q2;
            elseif q2 < -180
                q2 = 360 + q2;
            end

            % Show an error if the target is not within the bot's workspace
            if abs(q1) > app.MAX_ANG || abs(q2) > app.MAX_ANG
                error('Target location not reachable');
            end

            % Angles are valid, display on the GUI
            app.Link1degSlider.Value = q1;
            app.Link2degSlider.Value = q2;
            app.Link3mmSlider.Value = q3;

        end

        %% Update the graph
        function UpdateGraph(app)
            major_rad = 280;
            minor_rad = 140;
            angle = 45 *pi/180;
            
            g = app.UIAxes;


            % Main circle
            x0 = -major_rad:10:major_rad;
            y0 = sqrt(major_rad^2 - x0.^2);

            w1 = major_rad * cos(angle);
            x1a = -major_rad:10:-w1;
            y1a = -sqrt(major_rad^2 - x1a.^2);

            x1b = w1:10:major_rad;
            y1b = -sqrt(major_rad^2 - x1b.^2);


            %
            w2 = minor_rad * sin(angle);
            x2a = w2:10:w2+minor_rad;
            y2a = sqrt(minor_rad^2 - (x2a - minor_rad * cos(angle)) - minor_rad * sin(angle))


            hold(g, 'on');
            plot(g,x0,y0,'--g');
            plot(g,x1a,y1a,'--g');
            plot(g,x1b,y1b,'--g');
            plot(g,x2a,y2a,'--b');

            hold(g, 'off');

        end

        %% Initalise the robot
        function Robot = initRobot(app)

            % Get our global variables into smaller names
            d1 = app.ARM1_H;
            a1 = app.ARM1_L;
            d2 = app.ARM2_H;
            a2 = app.ARM2_L;
            d3 = app.EE_OS;

            % DH Table parameters
            %       θ  d   a   α   σ
            dh = [  0  d1  a1  0   0   ;
                    0  d2  a2  pi  0   ; 
                    0  d3  0   pi  1  ];

            % Generate robot
            Robot = SerialLink(dh);
            Robot.name = " ";

            % Set maximum angles/translations
            ang = app.MAX_ANG * pi/180;
            Robot.links(1).qlim = [-ang ang];
            Robot.links(2).qlim = [-ang ang];
            Robot.links(3).qlim = [d3 d3+100];

            % Show the robot DH params
            Robot.display
            
        end
        
        %% Run forward kinematics
        function forward(app)

            try    
                % Acquire angles from the UI
                q1 = app.Link1degSlider.Value;
                q2 = app.Link2degSlider.Value;
                q3 = app.Link3mmSlider.Value;

                % Parse angles into vector
                Q(1) = q1 * pi/180;
                Q(2) = q2 * pi/180;
                Q(3) = q3 + app.EE_OS;

                % Generate robot, run fkine, plot the result
                Robot = initRobot(app);
                T = Robot.fkine(Q)
                Robot.plot(Q, 'workspace', app.WS);

                % Update the GUI
                UpdateCoords(app, T);

            % Catch exceptions
            catch ex
                f = msgbox(ex.message,'An Error has occured');
            end

        end

        %% Run inverse kinematics
        function inverse(app, who)
             
            try
                % Read in the target coords from GUI
                X = app.XCoordinateSlider.Value;
                Y = app.YCoordinateSlider.Value;
                Z = app.ZCoordinateSlider.Value;
                
                % Input validation
                % Try to repair the target my moving it back into the
                % workspace of the robot
                arm1 = app.ARM1_L;
                arm2 = app.ARM2_L;
                angmax = app.MAX_ANG;
                cosmax = cos((180-angmax)*pi/180); % (COS(45 deg))

                xmin = -(arm1 * cosmax + arm2);

                if  X < xmin
                    X = xmin;
                end

                rtar = sqrt(X^2 + Y^2);
                rmin = sqrt(arm1^2 + arm2^2 - (2 * arm1 * arm2 * cosmax) );
                rmax = arm1 + arm2;

                if rtar > rmax
                    if who == 'X'
                        temp = Y;
                        Y = sqrt(rmax^2 - X^2);
                        if temp < 0
                            Y = -Y;
                        end
                    elseif who == 'Y'
                        temp = X;
                        X = sqrt(rmax^2 - Y^2);
                        if temp < 0
                            X = -X;
                        end
                    end

                elseif rtar < rmin
                    if who == 'X'
                        temp = Y;
                        Y = sqrt(rmin^2 - X^2);
                        if temp < 0
                            Y = -Y;
                        end
                    elseif who == 'Y'
                        temp = X;
                        X = sqrt(rmin^2 - Y^2);
                        if temp < 0
                            X = -X;
                        end
                    end
                end

                % Generate our target homogenous translation matrix
                T = transl(X, Y, Z);

                % Generate robot, run ikine, plot the result
                Robot = initRobot(app);
                Q = Robot.ikine(T, [260 10 72], 'mask', [1 1 1 0 0 0], 'verbose')
                Robot.plot(Q, 'workspace', app.WS);

                % Update the UI
                UpdateAngles(app, Q);
                UpdateCoords(app, T);

            catch ex
                f = msgbox(ex.message,'Invalid Operation');
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateGraph(app);
        end

        % Value changed function: XCoordinateSlider
        function XCoordinateSliderValueChanged(app, event)
            inverse(app, 'X');
        end

        % Value changed function: YCoordinateSlider
        function YCoordinateSliderValueChanged(app, event)
            inverse(app, 'Y');
        end

        % Value changed function: ZCoordinateSlider
        function ZCoordinateSliderValueChanged(app, event)
            inverse(app, 'Z');
        end

        % Value changed function: Link1degSlider
        function Link1degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link2degSlider
        function Link2degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link3mmSlider
        function Link3mmSliderValueChanged(app, event)
            forward(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ForwardKinematicsPanel = uipanel(app.GridLayout);
            app.ForwardKinematicsPanel.Title = 'Forward Kinematics';
            app.GridLayout2 = uigridlayout(app.ForwardKinematicsPanel);
            app.Link1degSliderLabel = uilabel(app.GridLayout2);
            app.Link1degSliderLabel.Text = 'Link1 (deg)';
            app.Link1degSlider = uislider(app.GridLayout2);
            app.Link1degSlider.Limits = [-135 135];
            app.Link1degSlider.ValueChangedFcn = createCallbackFcn(app, @Link1degSliderValueChanged, true);
            app.Link2degSliderLabel = uilabel(app.GridLayout2);
            app.Link2degSliderLabel.Text = 'Link2 (deg)';
            app.Link2degSlider = uislider(app.GridLayout2);
            app.Link2degSlider.Limits = [-135 135];
            app.Link2degSlider.ValueChangedFcn = createCallbackFcn(app, @Link2degSliderValueChanged, true);
            app.Link3mmSliderLabel = uilabel(app.GridLayout2);
            app.Link3mmSliderLabel.Text = 'Link3 (mm)';
            app.Link3mmSlider = uislider(app.GridLayout2);
            app.Link3mmSlider.ValueChangedFcn = createCallbackFcn(app, @Link3mmSliderValueChanged, true);
            app.InverseKinematicsPanel = uipanel(app.GridLayout);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.GridLayout3 = uigridlayout(app.InverseKinematicsPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'SCARA')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            subtitle(app.UIAxes, '(top down)')
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-300 300];
            app.XCoordinateSliderLabel = uilabel(app.GridLayout3);
            app.XCoordinateSliderLabel.Text = 'X Coordinate';
            app.XCoordinateSlider = uislider(app.GridLayout3);
            app.XCoordinateSlider.Limits = [-280 280];
            app.XCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordinateSliderValueChanged, true);
            app.XCoordinateSlider.Value = 280;
            app.YCoordinateSliderLabel = uilabel(app.GridLayout3);
            app.YCoordinateSliderLabel.Text = 'Y Coordinate';
            app.YCoordinateSlider = uislider(app.GridLayout3);
            app.YCoordinateSlider.Limits = [-280 280];
            app.YCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @YCoordinateSliderValueChanged, true);
            app.ZCoordinateSliderLabel = uilabel(app.GridLayout3);
            app.ZCoordinateSliderLabel.Text = 'Z Coordinate';
            app.ZCoordinateSlider = uislider(app.GridLayout3);
            app.ZCoordinateSlider.Limits = [22 122];
            app.ZCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @ZCoordinateSliderValueChanged, true);
            app.ZCoordinateSlider.Value = 122;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorApp_Grid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end