classdef ManipulatorApp_Sliders < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ForwardKinematicsLabel  matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
        ZCoordinateSlider       matlab.ui.control.Slider
        YCoordinateSlider       matlab.ui.control.Slider
        XCoordinateSlider       matlab.ui.control.Slider
        Link3mmSlider           matlab.ui.control.Slider
        Link2degSlider          matlab.ui.control.Slider
        Link1degSlider          matlab.ui.control.Slider
        ZCoordinateSliderLabel  matlab.ui.control.Label
        YCoordinateSliderLabel  matlab.ui.control.Label
        XCoordinateSliderLabel  matlab.ui.control.Label
        Link3mmSliderLabel      matlab.ui.control.Label
        Link2degSliderLabel     matlab.ui.control.Label
        Link1degSliderLabel     matlab.ui.control.Label
    end

    %% GLOBAL VARIABLES
    properties (Access = private)
        ARM1_L = 140;   % Arm1 length
        ARM1_H = 120;   % Arm1 height
        ARM2_L = 140;   % Arm2 length
        ARM2_H = 50;    % Arm2 height
        EE_OS = 48;     % EE distance below the arm
        MAX_ANG = 135;  % Maximum angle the arms can acheive

        WS = [-300 300 -300 300 0 200]; % Robot workspace
    end
    
    methods (Access = private)
        
        %% Update the X Y Z coords on the GUI
        function UpdateCoords(app, T)

            % Weird MATLAB issue - engineered a workaround
            try
                X = round(T.t(1));
                Y = round(T.t(2));
                Z = round(T.t(3));
            catch
                X = round(T(1,4));
                Y = round(T(2,4));
                Z = round(T(3,4));
            end

            % Apply the variables to the GUI
            app.XCoordinateSlider.Value = X;
            app.YCoordinateSlider.Value = Y;
            app.ZCoordinateSlider.Value = Z;

            % Display the coords
            fprintf('\nX: %d\nY: %d\nZ: %d\n\n', X, Y, Z)

        end

        %% Update the angles on the GUI
        function UpdateAngles(app, Q)
            
            % Parse the raw info from the matrix
            q1 = mod(round(Q(1)*180/pi),360);
            q2 = mod(round(Q(2)*180/pi),360);
            q3 = round(Q(3)-app.EE_OS);

            % Remove supernumerary full rotations
            % Move range between -180 -> +180
            if q1 > 180
                q1 = - 360 + q1;
            elseif q1 < -180
                q1 = 360 + q1;
            end
            if q2 > 180
                q2 = - 360 + q2;
            elseif q2 < -180
                q2 = 360 + q2;
            end

            % Show an error if the target is not within the bot's workspace
            if abs(q1) > app.MAX_ANG || abs(q2) > app.MAX_ANG
                error('Target location not reachable');
            end

            % Angles are valid, display on the GUI
            app.Link1degSlider.Value = q1;
            app.Link2degSlider.Value = q2;
            app.Link3mmSlider.Value = q3;

            % Display the angles
            fprintf('Angle 1: %d\nAngle 2: %d\nExtend: %d\n\n', q1, q2, q3)

        end

        %% Initalise the robot
        function Robot = initRobot(app)

            % Get our global variables into smaller names
            d1 = app.ARM1_H;
            a1 = app.ARM1_L;
            d2 = app.ARM2_H;
            a2 = app.ARM2_L;
            d3 = app.EE_OS;

            % DH Table parameters
            %       θ  d   a   α   σ
            dh = [  0  d1  a1  0   0   ;
                    0  d2  a2  pi  0   ; 
                    0  d3  0   pi  1  ];

            % Generate robot
            Robot = SerialLink(dh);
            Robot.name = " ";

            % Set maximum angles/translations
            ang = app.MAX_ANG * pi/180;
            Robot.links(1).qlim = [-ang ang];
            Robot.links(2).qlim = [-ang ang];
            Robot.links(3).qlim = [d3 d3+100];

        end
        
        %% Run forward kinematics
        function forward(app)

            try    
                disp('Forward Kinematics')

                % Acquire angles from the UI
                q1 = app.Link1degSlider.Value;
                q2 = app.Link2degSlider.Value;
                q3 = app.Link3mmSlider.Value;

                % Parse angles into vector
                Q(1) = q1 * pi/180;
                Q(2) = q2 * pi/180;
                Q(3) = q3 + app.EE_OS;

                % Generate robot, run fkine, plot the result
                Robot = initRobot(app);
                T = Robot.fkine(Q)
                Robot.plot(Q, 'workspace', app.WS);

                % Update the GUI
                UpdateCoords(app, T);
                UpdateAngles(app, Q);

                disp('Complete')

            % Catch exceptions
            catch ex
                disp('Forward Kinematics ERROR')
                f = msgbox(ex.message,'An Error has occured');
            end

        end

        %% Run inverse kinematics
        function inverse(app, who)
             
            try
                disp('Inverse Kinematics')

                % Read in the target coords from GUI
                X = app.XCoordinateSlider.Value;
                Y = app.YCoordinateSlider.Value;
                Z = app.ZCoordinateSlider.Value;
                
                % Input validation
                % Try to repair the target my moving it back into the
                % workspace of the robot
                arm1 = app.ARM1_L;
                arm2 = app.ARM2_L;
                angmax = app.MAX_ANG;
                cosmax = cos((180-angmax)*pi/180); % (COS(45 deg))

                xmin = -(arm1 * cosmax + arm2);

                if  X < xmin
                    X = xmin;
                    app.XCoordinateSlider.Value = xmin;
                end

                rtar = sqrt(X^2 + Y^2);
                rmin = sqrt(arm1^2 + arm2^2 - (2 * arm1 * arm2 * cosmax) );
                rmax = arm1 + arm2;

                if rtar > rmax
                    if who == 'X'
                        temp = Y;
                        Y = sqrt(rmax^2 - X^2);
                        if temp < 0
                            Y = -Y;
                        end
                    elseif who == 'Y'
                        temp = X;
                        X = sqrt(rmax^2 - Y^2);
                        if temp < 0
                            X = -X;
                        end
                    end

                elseif rtar < rmin
                    if who == 'X'
                        temp = Y;
                        Y = sqrt(rmin^2 - X^2);
                        if temp < 0
                            Y = -Y;
                        end
                    elseif who == 'Y'
                        temp = X;
                        X = sqrt(rmin^2 - Y^2);
                        if temp < 0
                            X = -X;
                        end
                    end
                end

                % Generate our target homogenous translation matrix
                T = transl(X, Y, Z);

                % Generate robot, run ikine, plot the result
                Robot = initRobot(app);
                Q = Robot.ikine(T, [260 10 72], 'mask', [1 1 1 0 0 0], 'verbose')
                %Q = Robot.ikcon(T)
                Robot.plot(Q, 'workspace', app.WS);

                % Update the UI
                UpdateCoords(app, T);
                UpdateAngles(app, Q);
                
                disp('Complete')

            catch ex
                disp('Inverse Kinematics ERROR')
                f = msgbox(ex.message,'Invalid Operation');
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Robot = initRobot(app);
            disp('Robots DH table:')
            Robot.sym

            syms q1 q2 q3
    
            disp('fkine pose transformation matrix:')
            Robot.fkine([q1 q2 (q3 + app.EE_OS)])
            
        end

        % Value changed function: XCoordinateSlider
        function XCoordinateSliderValueChanged(app, event)
            inverse(app, 'X');
        end

        % Value changed function: YCoordinateSlider
        function YCoordinateSliderValueChanged(app, event)
            inverse(app, 'Y');
        end

        % Value changed function: ZCoordinateSlider
        function ZCoordinateSliderValueChanged(app, event)
            inverse(app, 'Z');
        end

        % Value changed function: Link1degSlider
        function Link1degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link2degSlider
        function Link2degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link3mmSlider
        function Link3mmSliderValueChanged(app, event)
            forward(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Link1degSliderLabel = uilabel(app.GridLayout);
            app.Link1degSliderLabel.Text = 'Link1 (deg)';
            app.Link2degSliderLabel = uilabel(app.GridLayout);
            app.Link2degSliderLabel.Text = 'Link2 (deg)';
            app.Link3mmSliderLabel = uilabel(app.GridLayout);
            app.Link3mmSliderLabel.Text = 'Link3 (mm)';
            app.XCoordinateSliderLabel = uilabel(app.GridLayout);
            app.XCoordinateSliderLabel.Text = 'X Coordinate';
            app.YCoordinateSliderLabel = uilabel(app.GridLayout);
            app.YCoordinateSliderLabel.Text = 'Y Coordinate';
            app.ZCoordinateSliderLabel = uilabel(app.GridLayout);
            app.ZCoordinateSliderLabel.Text = 'Z Coordinate';
            app.Link1degSlider = uislider(app.GridLayout);
            app.Link1degSlider.Limits = [-135 135];
            app.Link1degSlider.ValueChangedFcn = createCallbackFcn(app, @Link1degSliderValueChanged, true);
            app.Link2degSlider = uislider(app.GridLayout);
            app.Link2degSlider.Limits = [-135 135];
            app.Link2degSlider.ValueChangedFcn = createCallbackFcn(app, @Link2degSliderValueChanged, true);
            app.Link3mmSlider = uislider(app.GridLayout);
            app.Link3mmSlider.ValueChangedFcn = createCallbackFcn(app, @Link3mmSliderValueChanged, true);
            app.XCoordinateSlider = uislider(app.GridLayout);
            app.XCoordinateSlider.Limits = [-280 280];
            app.XCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordinateSliderValueChanged, true);
            app.XCoordinateSlider.Value = 280;
            app.YCoordinateSlider = uislider(app.GridLayout);
            app.YCoordinateSlider.Limits = [-280 280];
            app.YCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @YCoordinateSliderValueChanged, true);
            app.ZCoordinateSlider = uislider(app.GridLayout);
            app.ZCoordinateSlider.Limits = [22 122];
            app.ZCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @ZCoordinateSliderValueChanged, true);
            app.ZCoordinateSlider.Value = 122;
            app.InverseKinematicsLabel = uilabel(app.GridLayout);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.ForwardKinematicsLabel = uilabel(app.GridLayout);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorApp_Sliders

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end